classdef arayuz2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        ResultLabel        matlab.ui.control.Label
        sinif              matlab.ui.control.Button
        resim              matlab.ui.control.Button
        ayarDropDown       matlab.ui.control.DropDown
        AYARDropDownLabel  matlab.ui.control.Label
        optDropDown        matlab.ui.control.DropDown
        OPTMZASYONALGORTMASIDropDownLabel  matlab.ui.control.Label
        agDropDown         matlab.ui.control.DropDown
        ASEMDropDownLabel  matlab.ui.control.Label
        ImageAxes          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        imageData
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: resim
        function resimPushed(app, event)
            [file, path] = uigetfile('*.jpg', 'Bir resim seçin');
            if isequal(file,0)
                return; % Kullanıcı seçim yapmadan kapattıysa işlemi iptal et
            end
            fullPath = fullfile(path, file);        % Tam dosya yolunu al
            app.imageData = imread(fullPath);       % Resmi oku (imageData isimli değişkende sakla)
            imshow(app.imageData, 'Parent', app.ImageAxes); % UIAxes içine resmi göster

        end

        % Button pushed function: sinif
        function sinifButtonPushed(app, event)

            % 0. Resim yüklendi mi kontrol et
            if isempty(app.imageData)
                uialert(app.UIFigure, 'Lütfen önce bir resim yükleyin.', 'Resim Eksik');
                return; % işlemi durdur
            end

            % 1. Ağ ve ayar bilgilerini al
            network = app.agDropDown.Value;
            optimizer = app.optDropDown.Value;
            ayar = app.ayarDropDown.Value;

            % 2. Dosya adını oluştur
            dosyaAdi = sprintf('%s_%s_%s.mat', network, optimizer, ayar);  % Örn: MyCNN_SGDM_Yüksek Ayar.mat

            try
                modelData = load(dosyaAdi);
            catch
                uialert(app.UIFigure, 'Model dosyası bulunamadı veya açılamadı.', 'Dosya Hatası');
                return;
            end

            % 3.1 Model içindeki doğru değişkeni bul
            if isfield(modelData, "net")
                net = modelData.net;
            elseif isfield(modelData, "netTransfer")
                net = modelData.netTransfer;
            else
                uialert(app.UIFigure, 'Model dosyasında geçerli bir ağ (net/netTransfer) bulunamadı.', 'Model Hatası');
                return;
            end
            % 4. Görseli yeniden boyutlandır
            inputImage = imresize(app.imageData, [224 224]);

            % Gri görüntüyse RGB yap
            if size(inputImage, 3) == 1
                inputImage = cat(3, inputImage, inputImage, inputImage);
            end

            % 5. Girdi resmini sınıflandır
            label = classify(net, inputImage);

            % 6. Sonucu yazdır
            if label == "yes"
                app.ResultLabel.Text = "Tümör Var";
            else
                app.ResultLabel.Text = "Tümör Yok";
            end

        end

        % Button down function: ImageAxes
        function ImageAxesButtonDown(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ImageAxes = uiaxes(app.UIFigure);
            app.ImageAxes.ButtonDownFcn = createCallbackFcn(app, @ImageAxesButtonDown, true);
            app.ASEMDropDownLabel = uilabel(app.UIFigure);
            app.ASEMDropDownLabel.Text = 'AĞ SEÇİMİ';
            app.agDropDown = uidropdown(app.UIFigure);
            app.agDropDown.Items = {'MyCNN', 'MobileNet', 'GoogLeNet'};
            app.agDropDown.Value = 'MyCNN';
            app.OPTMZASYONALGORTMASIDropDownLabel = uilabel(app.UIFigure);
            app.OPTMZASYONALGORTMASIDropDownLabel.Text = {'OPTİMİZASYON'; 'ALGORİTMASI'};
            app.optDropDown = uidropdown(app.UIFigure);
            app.optDropDown.Items = {'Adam', 'SGDM', 'RMSProp'};
            app.optDropDown.Value = 'Adam';
            app.AYARDropDownLabel = uilabel(app.UIFigure);
            app.AYARDropDownLabel.Text = 'AYAR';
            app.ayarDropDown = uidropdown(app.UIFigure);
            app.ayarDropDown.Items = {'Yüksek Ayar', 'Düşük Ayar'};
            app.ayarDropDown.Value = 'Yüksek Ayar';
            app.resim = uibutton(app.UIFigure, 'push');
            app.resim.ButtonPushedFcn = createCallbackFcn(app, @resimPushed, true);
            app.resim.Text = 'RESİM YÜKLE';
            app.sinif = uibutton(app.UIFigure, 'push');
            app.sinif.ButtonPushedFcn = createCallbackFcn(app, @sinifButtonPushed, true);
            app.sinif.Text = 'SINIFLANDIRMA';
            app.ResultLabel = uilabel(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = arayuz2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end